classdef FinaleProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BYBenEfrathBarYerushalmianLabel  matlab.ui.control.Label
        StereoMatchingObjectsDistanceFromCameraLabel  matlab.ui.control.Label
        BaseLinecmTextArea          matlab.ui.control.TextArea
        BaseLinecmTextAreaLabel     matlab.ui.control.Label
        readyLamp                   matlab.ui.control.Lamp
        readyLampLabel              matlab.ui.control.Label
        KnownDistancesTextArea      matlab.ui.control.TextArea
        KnownDistancesLabel         matlab.ui.control.Label
        DistancecmTextArea          matlab.ui.control.TextArea
        DistancecmLabel             matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
        FocalLengthPixelsTextArea   matlab.ui.control.TextArea
        FocalLengthPixelsLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ChoosePictureDropDown       matlab.ui.control.DropDown
        ChoosePictureDropDownLabel  matlab.ui.control.Label
        ChooseCameraDropDown        matlab.ui.control.DropDown
        ChooseCameraLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        var=load("CamerasData.mat");
        f;
        Ileft;
        Iright;
        T;
        Z;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseCameraDropDown
        function ChooseCameraDropDownValueChanged(app, event)
            value = app.ChooseCameraDropDown.Value;
              
            if value == "Galaxy S10"
                app.f=app.var.CamerasData.CameraBen.FocalLength;
            end
            if value == "Iphone 12 Pro"
                app.f=app.var.CamerasData.CameraBar.FocalLength;
            end
            app.FocalLengthPixelsTextArea.Value=num2str(app.f);
        end

        % Clicked callback: ChooseCameraDropDown
        function ChooseCameraDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: ChoosePictureDropDown
        function ChoosePictureDropDownValueChanged(app, event)
            value = app.ChoosePictureDropDown.Value;
            if app.ChooseCameraDropDown.Value=="Galaxy S10"
                switch value
                    case "Picture 1"
                        app.Ileft=app.var.CamerasData.CameraBen.Image1.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBen.Image1.RightImage;
                        app.T=app.var.CamerasData.CameraBen.Image1.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBen.Image1.KnownDistances);
                    
                    case "Picture 2"
                        app.Ileft=app.var.CamerasData.CameraBen.Image2.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBen.Image2.RightImage;
                        app.T=app.var.CamerasData.CameraBen.Image2.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBen.Image2.KnownDistances);

                    case "Picture 3"
                        app.Ileft=app.var.CamerasData.CameraBen.Image3.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBen.Image3.RightImage;
                        app.T=app.var.CamerasData.CameraBen.Image3.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBen.Image3.KnownDistances);
                    case "Picture 4"
                        app.Ileft=app.var.CamerasData.CameraBen.Image4.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBen.Image4.RightImage;
                        app.T=app.var.CamerasData.CameraBen.Image4.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBen.Image4.KnownDistances);
                end
            end
            if app.ChooseCameraDropDown.Value=="Iphone 12 Pro"
                switch value
                    case "Picture 1"
                        app.Ileft=app.var.CamerasData.CameraBar.Image1.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBar.Image1.RightImage;
                        app.T=app.var.CamerasData.CameraBar.Image1.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBar.Image1.KnownDistances);
                    
                    case "Picture 2"
                        app.Ileft=app.var.CamerasData.CameraBar.Image2.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBar.Image2.RightImage;
                        app.T=app.var.CamerasData.CameraBar.Image2.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBar.Image2.KnownDistances);

                   case "Picture 3"
                        app.Ileft=app.var.CamerasData.CameraBar.Image3.LeftImage;
                        app.Iright=app.var.CamerasData.CameraBar.Image3.RightImage;
                        app.T=app.var.CamerasData.CameraBar.Image3.T;
                        app.KnownDistancesTextArea.Value=sprintf(app.var.CamerasData.CameraBar.Image3.KnownDistances);
                end
            end
          
          app.BaseLinecmTextArea.Value=num2str(app.T);
          app.Image.ImageSource=app.Ileft;
        end

        % Clicked callback: ChoosePictureDropDown
        function ChoosePictureDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.readyLamp.Color='red';
            if app.Image.ImageSource==0
                app.readyLamp.Color='green';
                return
            end
%             selectObj = figure();
%             imshow(app.Ileft);
%             title('Select an object by drawing a rectangle');
%             rect = getrect; % Get the coordinates of the selected rectangle [x, y, width, height]
%             % Extract the object from the first image
%             object_Mask = imcrop(app.Ileft, rect);
%             close (selectObj);
%             
%             app.Image_2.ImageSource=object_Mask;

        f=app.f;
        T=app.T;
        Ileft=app.Ileft;
        Iright=app.Iright;
        app.Z=DistanceCalc(Ileft,Iright,f,T);
        app.DistancecmTextArea.Value=num2str(app.Z);
        app.readyLamp.Color='green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseCameraLabel = uilabel(app.UIFigure);
            app.ChooseCameraLabel.Text = 'Choose Camera:';
            app.ChooseCameraDropDown = uidropdown(app.UIFigure);
            app.ChooseCameraDropDown.Items = {'None', 'Iphone 12 Pro', 'Galaxy S10'};
            app.ChooseCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCameraDropDownValueChanged, true);
            app.ChooseCameraDropDown.ClickedFcn = createCallbackFcn(app, @ChooseCameraDropDownClicked, true);
            app.ChooseCameraDropDown.Value = 'None';
            app.ChoosePictureDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePictureDropDownLabel.Text = 'Choose Picture:';
            app.ChoosePictureDropDown = uidropdown(app.UIFigure);
            app.ChoosePictureDropDown.Items = {'None', 'Picture 1', 'Picture 2', 'Picture 3', 'Picture 4'};
            app.ChoosePictureDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePictureDropDownValueChanged, true);
            app.ChoosePictureDropDown.ClickedFcn = createCallbackFcn(app, @ChoosePictureDropDownClicked, true);
            app.ChoosePictureDropDown.Value = 'None';
            app.Image = uiimage(app.UIFigure);
            app.FocalLengthPixelsLabel = uilabel(app.UIFigure);
            app.FocalLengthPixelsLabel.Text = 'Focal Length [Pixels]:';
            app.FocalLengthPixelsTextArea = uitextarea(app.UIFigure);
            app.FocalLengthPixelsTextArea.Editable = 'off';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.DistancecmLabel = uilabel(app.UIFigure);
            app.DistancecmLabel.Text = 'Distance [cm]:';
            app.DistancecmTextArea = uitextarea(app.UIFigure);
            app.DistancecmTextArea.Editable = 'off';
            app.KnownDistancesLabel = uilabel(app.UIFigure);
            app.KnownDistancesLabel.Text = 'Known Distances:';
            app.KnownDistancesTextArea = uitextarea(app.UIFigure);
            app.KnownDistancesTextArea.Editable = 'off';
            app.readyLampLabel = uilabel(app.UIFigure);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.UIFigure);
            app.BaseLinecmTextAreaLabel = uilabel(app.UIFigure);
            app.BaseLinecmTextAreaLabel.Text = 'BaseLine [cm]:';
            app.BaseLinecmTextArea = uitextarea(app.UIFigure);
            app.BaseLinecmTextArea.Editable = 'off';
            app.StereoMatchingObjectsDistanceFromCameraLabel = uilabel(app.UIFigure);
            app.StereoMatchingObjectsDistanceFromCameraLabel.Text = 'Stereo Matching: Object''s Distance From Camera';
            app.BYBenEfrathBarYerushalmianLabel = uilabel(app.UIFigure);
            app.BYBenEfrathBarYerushalmianLabel.Text = {' BY:'; ''; 'Ben Efrath'; '&'; 'Bar Yerushalmian'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinaleProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end